classdef graphing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Lamp                          matlab.ui.control.Lamp
        PressCotrolButton             matlab.ui.control.Button
        update                        matlab.ui.control.Button
        ControlPanel                  matlab.ui.container.Panel
        ZoomLabel                     matlab.ui.control.Label
        C_yLabel                      matlab.ui.control.Label
        C_xLabel                      matlab.ui.control.Label
        g                             matlab.ui.control.NumericEditField
        Dg                            matlab.ui.control.Button
        Ug                            matlab.ui.control.Button
        C_x                           matlab.ui.control.NumericEditField
        DC_x                          matlab.ui.control.Button
        UC_x                          matlab.ui.control.Button
        C_y                           matlab.ui.control.NumericEditField
        DC_y                          matlab.ui.control.Button
        UC_y                          matlab.ui.control.Button
        MaxIt                         matlab.ui.control.NumericEditField
        MaxItterationsEditFieldLabel  matlab.ui.control.Label
        N                             matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel      matlab.ui.control.Label
        Ax                            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp.Color=[1,0,0];
            drawnow;
            mandelbrot(app.N.Value, app.MaxIt.Value, app.C_x.Value, app.C_y.Value, app.g.Value,app.Ax);
            app.Lamp.Color=[0,1,0];
            drawnow;
        end

        % Button pushed function: Ug
        function UgButtonPushed(app, event)
            app.g.Value=0.8*app.g.Value;
            startupFcn(app);
        end

        % Button pushed function: Dg
        function DgButtonPushed(app, event)
            app.g.Value=1.2*app.g.Value;
            startupFcn(app);
        end

        % Button pushed function: UC_y
        function UC_yButtonPushed(app, event)
            app.C_y.Value=0.2*app.g.Value+app.C_y.Value;
            startupFcn(app);
        end

        % Button pushed function: DC_y
        function DC_yButtonPushed(app, event)
            app.C_y.Value=-0.2*app.g.Value+app.C_y.Value;
            startupFcn(app);
        end

        % Button pushed function: UC_x
        function UC_xButtonPushed(app, event)
            app.C_x.Value=0.2*app.g.Value+app.C_x.Value;
            startupFcn(app);
        end

        % Button pushed function: DC_x
        function DC_xButtonPushed(app, event)
            app.C_x.Value=-0.2*app.g.Value+app.C_x.Value;
            startupFcn(app);
        end

        % Button pushed function: update
        function updateButtonPushed(app, event)
            startupFcn(app);
        end

        % Button pushed function: PressCotrolButton
        function PressCotrolButtonPushed(app, event)
            t=get( 0, 'Screensize' );
            figure('KeyPressFcn', @KeyFcn, 'Name', 'Control', 'Position', [t(3),t(4),t(3)/20,t(4)/20]); % new traditional figure with interactive features
            
            function KeyFcn(~, ~)
                Key=get(gcf,'CurrentKey');
                %using strcmp for string comparison if comparison is true = 1
                if strcmp(Key,'leftarrow')
                    DC_xButtonPushed(app, event);
                elseif strcmp(Key,'rightarrow')
                    UC_xButtonPushed(app, event);
                elseif strcmp(Key,'uparrow')
                    UC_yButtonPushed(app, event);
                elseif strcmp(Key,'downarrow')
                    DC_yButtonPushed(app, event);
                elseif strcmp(Key,'q')
                    UgButtonPushed(app, event);
                elseif strcmp(Key,'a')
                    DgButtonPushed(app, event);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Ax = uiaxes(app.UIFigure);
            title(app.Ax, 'Pretty math')
            xlabel(app.Ax, 'X')
            ylabel(app.Ax, 'Y')
            app.ResolutionEditFieldLabel = uilabel(app.UIFigure);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.N = uieditfield(app.UIFigure, 'numeric');
            app.N.Value = 300;
            app.MaxItterationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxItterationsEditFieldLabel.Text = 'MaxItterations';
            app.MaxIt = uieditfield(app.UIFigure, 'numeric');
            app.MaxIt.Value = 100;
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.UC_y = uibutton(app.ControlPanel, 'push');
            app.UC_y.ButtonPushedFcn = createCallbackFcn(app, @UC_yButtonPushed, true);
            app.UC_y.Text = '+';
            app.DC_y = uibutton(app.ControlPanel, 'push');
            app.DC_y.ButtonPushedFcn = createCallbackFcn(app, @DC_yButtonPushed, true);
            app.DC_y.Text = '-';
            app.C_y = uieditfield(app.ControlPanel, 'numeric');
            app.UC_x = uibutton(app.ControlPanel, 'push');
            app.UC_x.ButtonPushedFcn = createCallbackFcn(app, @UC_xButtonPushed, true);
            app.UC_x.Text = '+';
            app.DC_x = uibutton(app.ControlPanel, 'push');
            app.DC_x.ButtonPushedFcn = createCallbackFcn(app, @DC_xButtonPushed, true);
            app.DC_x.Text = '-';
            app.C_x = uieditfield(app.ControlPanel, 'numeric');
            app.Ug = uibutton(app.ControlPanel, 'push');
            app.Ug.ButtonPushedFcn = createCallbackFcn(app, @UgButtonPushed, true);
            app.Ug.Text = '+';
            app.Dg = uibutton(app.ControlPanel, 'push');
            app.Dg.ButtonPushedFcn = createCallbackFcn(app, @DgButtonPushed, true);
            app.Dg.Text = '-';
            app.g = uieditfield(app.ControlPanel, 'numeric');
            app.g.Value = 1;
            app.C_xLabel = uilabel(app.ControlPanel);
            app.C_xLabel.Text = 'C_x';
            app.C_yLabel = uilabel(app.ControlPanel);
            app.C_yLabel.Text = 'C_y';
            app.ZoomLabel = uilabel(app.ControlPanel);
            app.ZoomLabel.Text = 'Zoom';
            app.update = uibutton(app.UIFigure, 'push');
            app.update.ButtonPushedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.update.Text = 'Update';
            app.PressCotrolButton = uibutton(app.UIFigure, 'push');
            app.PressCotrolButton.ButtonPushedFcn = createCallbackFcn(app, @PressCotrolButtonPushed, true);
            app.PressCotrolButton.Text = 'Press Cotrol';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graphing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end